classdef DSP_PROJECT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UITable               matlab.ui.control.Table
        nSeqEditField_2       matlab.ui.control.EditField
        nSeqEditField_2Label  matlab.ui.control.Label
        nSeqEditFieldLabel_6  matlab.ui.control.Label
        nSeqEditFieldLabel_5  matlab.ui.control.Label
        nSeqEditFieldLabel_4  matlab.ui.control.Label
        nSeqEditFieldLabel_3  matlab.ui.control.Label
        nSeqEditFieldLabel_2  matlab.ui.control.Label
        DIEditField           matlab.ui.control.EditField
        DIEditFieldLabel      matlab.ui.control.Label
        TextArea              matlab.ui.control.TextArea
        TextAreaLabel         matlab.ui.control.Label
        READTXTFILEButton     matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: READTXTFILEButton
        function READTXTFILEButtonPushed(app, event)

%READ TEXT FILE KNOP
%KIES EEN FILE IN DATA/PP01 FOLDER
%TOON FILE TITEL IN EDIT FIELD
%TOON FILE CONTENT IN TEXTAREA

[filename,path]=uigetfile("DATA/PP01/*.txt");
%app.OUTPUTEditField.Value = filename;
pathToFile = path + "/" + filename;
fileID = fopen(pathToFile);
A = readmatrix(pathToFile);
%%app.TextArea.Value = A;
%%app.TextArea.FontSize = 20;
vars = {'test1','test1','test1','test1','test1','test1','test1'};
fig = uifigure;
app.UITable = uitable(fig, 'Data',A);
fclose(fileID);
figure(app.UIFigure);


        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
%             value = app.TextArea.Value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.READTXTFILEButton = uibutton(app.UIFigure, 'push');
            app.READTXTFILEButton.ButtonPushedFcn = createCallbackFcn(app, @READTXTFILEButtonPushed, true);
            app.READTXTFILEButton.Text = 'READ TXT FILE';
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.DIEditFieldLabel = uilabel(app.UIFigure);
            app.DIEditFieldLabel.Text = 'DI';
            app.DIEditField = uieditfield(app.UIFigure, 'text');
            app.nSeqEditFieldLabel_2 = uilabel(app.UIFigure);
            app.nSeqEditFieldLabel_2.Text = 'CH1';
            app.nSeqEditFieldLabel_3 = uilabel(app.UIFigure);
            app.nSeqEditFieldLabel_3.Text = 'CH3';
            app.nSeqEditFieldLabel_4 = uilabel(app.UIFigure);
            app.nSeqEditFieldLabel_4.Text = 'CH4';
            app.nSeqEditFieldLabel_5 = uilabel(app.UIFigure);
            app.nSeqEditFieldLabel_5.Text = 'CH5';
            app.nSeqEditFieldLabel_6 = uilabel(app.UIFigure);
            app.nSeqEditFieldLabel_6.Text = 'CH2';
            app.nSeqEditField_2Label = uilabel(app.UIFigure);
            app.nSeqEditField_2Label.Text = 'nSeq';
            app.nSeqEditField_2 = uieditfield(app.UIFigure, 'text');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DSP_PROJECT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end